classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        AddButton_3               matlab.ui.control.Button
        AddButton_2               matlab.ui.control.Button
        AddButton                 matlab.ui.control.Button
        StepEditField             matlab.ui.control.NumericEditField
        StepEditFieldLabel        matlab.ui.control.Label
        EditField2_3              matlab.ui.control.EditField
        EditField_5               matlab.ui.control.EditField
        DropDown2                 matlab.ui.control.DropDown
        DropDown_2                matlab.ui.control.DropDown
        DropDown                  matlab.ui.control.DropDown
        EditField3                matlab.ui.control.NumericEditField
        EditField2_2              matlab.ui.control.NumericEditField
        EditField_4               matlab.ui.control.NumericEditField
        PlotButton                matlab.ui.control.Button
        ListBox3                  matlab.ui.control.ListBox
        ListBox2                  matlab.ui.control.ListBox
        ListBox                   matlab.ui.control.ListBox
        EditField_3Label_3        matlab.ui.control.Label
        EditField_3Label_2        matlab.ui.control.Label
        EditField_3Label          matlab.ui.control.Label
        UpperBoundEditField       matlab.ui.control.NumericEditField
        UpperBoundEditFieldLabel  matlab.ui.control.Label
        LowerBoundEditField       matlab.ui.control.NumericEditField
        LowerBoundEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
            xVariables = cell(1, value);
            for i = 1:value
                xVariables{i} = sprintf('x%d', i);
            end

            % Update the dropdown options
            app.DropDown.Items = xVariables;
        end

        % Value changed function: EditField2_2
        function EditField2_2ValueChanged(app, event)
            value = app.EditField2_2.Value;
            fVariables = cell(1, value);
            for i = 1:value
                fVariables{i} = sprintf('f%d', i);
            end

            % Update the dropdown options
            app.DropDown_2.Items = fVariables;
        end

        % Value changed function: EditField3
        function EditField3ValueChanged(app, event)
            value = app.EditField3.Value;
            gVariables = cell(1, value);
            for i = 1:value
                gVariables{i} = sprintf('g%d', i);
            end

            % Update the dropdown options
            app.DropDown2.Items = gVariables;
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
           % % % drop down for x
            selectedVariable = app.DropDown.Value;

            % Get values from Upper Bound, Lower Bound, and Step NumericEditFields
            upperBound = app.UpperBoundEditField.Value;
            lowerBound = app.LowerBoundEditField.Value;
            stepValue = app.StepEditField.Value;

            % Create a string based on the user's selections
            listBoxString = sprintf('%s | %g | %g | %g', selectedVariable, lowerBound, upperBound, stepValue);

            % Append the string to the ListBox
            currentList = app.ListBox.Items;
            currentList{end + 1} = listBoxString;
            app.ListBox.Items = currentList;
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            % % % Add button for x
            selectedVariable = app.DropDown.Value;

            % Get values from Upper Bound, Lower Bound, and Step NumericEditFields
            upperBound = app.UpperBoundEditField.Value;
            lowerBound = app.LowerBoundEditField.Value;
            stepValue = app.StepEditField.Value;

            % Create a string based on the user's selections
            listBoxString = sprintf('%s | %g | %g | %g', selectedVariable, lowerBound, upperBound, stepValue);

            % % Append the string to the ListBox
            % currentList = app.ListBox.Items;
            % currentList{end + 1} = listBoxString;
            % app.ListBox.Items = currentList;
            
            %  % Check if an entry already exists for the selected variable
            % existingIndex = find(contains(app.ListBox.Items, selectedVariable), 1);
            % 
            % if ~isempty(existingIndex)
            %     % Update the existing entry
            %     app.ListBox.Items{existingIndex} = listBoxString;
            % else
            %     % Append the string to the ListBox
            %     currentList = app.ListBox.Items;
            %     currentList{end + 1} = listBoxString;
            %     app.ListBox.Items = currentList;
            % end
            % Check if an entry already exists for the selected variable
            existingIndices = find(contains(app.ListBox.Items, selectedVariable));

            % Remove existing entries for the selected variable
            app.ListBox.Items(existingIndices) = [];

            % Append the new string to the ListBox
            currentList = app.ListBox.Items;
            currentList{end + 1} = listBoxString;
            app.ListBox.Items = currentList;

            % % Extract and store values in separate variables
            % values = sscanf(listBoxString, '%*s | %f | %f | %f');
            % lowerBoundValue = values(1);
            % upperBoundValue = values(2);
            % stepBoxValue = values(3);
            % 
            % % Store the values in arrays or cell arrays
            % % Depending on how you want to organize the data
            % app1.LowerBoundValues.(selectedVariable) = lowerBoundValue;
            % app1.UpperBoundValues.(selectedVariable) = upperBoundValue;
            % app1.StepBoxValues.(selectedVariable) = stepBoxValue;
            % 
            % % Display the extracted values in the Command Window
            % disp(['Lower Bound for ', selectedVariable, ': ', num2str(lowerBoundValue)]);
            % disp(['Upper Bound for ', selectedVariable, ': ', num2str(upperBoundValue)]);
            % disp(['Step for ', selectedVariable, ': ', num2str(stepBoxValue)]);

        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            % % % drop down for f
            selectedVariable = app.DropDown_2.Value;

            % Get values from Upper Bound, Lower Bound, and Step NumericEditFields
            funct = app.EditField_5.Value;

            % Create a string based on the user's selections
            listBoxString = sprintf('%s | %s', selectedVariable, funct);

            % Append the string to the ListBox
            currentList = app.ListBox2.Items;
            currentList{end + 1} = listBoxString;
            app.ListBox2.Items = currentList;
            
        end

        % Button pushed function: AddButton_2
        function AddButton_2Pushed(app, event)
            % % % Add button for f
            selectedVariable = app.DropDown_2.Value;

            % Get values from Upper Bound, Lower Bound, and Step NumericEditFields
            funct = app.EditField_5.Value;

            % Create a string based on the user's selections
            listBoxString = sprintf('%s | %s', selectedVariable, funct);

            % % Append the string to the ListBox
            % currentList = app.ListBox2.Items;
            % currentList{end + 1} = listBoxString;
            % app.ListBox2.Items = currentList;

            % Check if an entry already exists for the selected variable
            existingIndices = find(contains(app.ListBox2.Items, selectedVariable));

            % Remove existing entries for the selected variable
            app.ListBox2.Items(existingIndices) = [];

            % Append the new string to the ListBox
            currentList = app.ListBox2.Items;
            currentList{end + 1} = listBoxString;
            app.ListBox2.Items = currentList;
        end

        % Value changed function: DropDown2
        function DropDown2ValueChanged(app, event)
            % % % drop down for g
            selectedVariable = app.DropDown2.Value;

            % Get values from Upper Bound, Lower Bound, and Step NumericEditFields
            const = app.EditField2_3.Value;

            % Create a string based on the user's selections
            listBoxString = sprintf('%s | %s', selectedVariable, const);

            % Append the string to the ListBox
            currentList = app.ListBox3.Items;
            currentList{end + 1} = listBoxString;
            app.ListBox3.Items = currentList;
            
        end

        % Button pushed function: AddButton_3
        function AddButton_3Pushed(app, event)
            % % % Add button for g
            selectedVariable = app.DropDown2.Value;

            % Get values from Upper Bound, Lower Bound, and Step NumericEditFields
            const = app.EditField2_3.Value;

            % Create a string based on the user's selections
            listBoxString = sprintf('%s | %s', selectedVariable, const);

            % % Append the string to the ListBox
            % currentList = app.ListBox3.Items;
            % currentList{end + 1} = listBoxString;
            % app.ListBox3.Items = currentList;

            % Check if an entry already exists for the selected variable
            existingIndices = find(contains(app.ListBox3.Items, selectedVariable));

            % Remove existing entries for the selected variable
            app.ListBox3.Items(existingIndices) = [];

            % Append the new string to the ListBox
            currentList = app.ListBox3.Items;
            currentList{end + 1} = listBoxString;
            app.ListBox3.Items = currentList;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % Initialize arrays to store lower and upper bounds
            app.PlotButton.UserData = [];
            VarName = {}; % Initialize a cell array for variable names

            lowerBounds = [];
            upperBounds = [];
            stepboxs = [];
            % Get the items from the ListBox
            listBoxItems = app.ListBox.Items;

            % Iterate through ListBox items
            for i = 1:numel(listBoxItems) % Start from 2 to skip the header row
                row = listBoxItems{i};
                % disp(row)
                % Split the row into cells based on '|'
                rowCells = strsplit(row, '|');
                % Trim whitespace from each cell
                rowCells = strtrim(rowCells);
                
                % disp(rowCells{1})
                % disp(rowCells{2})
                % disp(rowCells{3})
                % Extract the lower and upper bounds
                lowerBound = str2num(rowCells{2});
                upperBound = str2num(rowCells{3});
                stepbox = str2num(rowCells{4});
                % disp(lowerBounds)
                % disp(upperBounds)
                % Append the extracted bounds to the arrays
                lowerBounds = [lowerBounds, lowerBound];
                upperBounds = [upperBounds, upperBound];
                stepboxs = [stepboxs, stepbox];
            end  
            % disp(lowerBounds)
            % disp(upperBounds)
            % disp(stepboxs)
            %DOE generation
            Xlhs = lhsdesign(stepboxs(1),size(lowerBounds,2));
            X = lowerBounds + Xlhs.*(upperBounds-lowerBounds);
            % disp(X);
%             %% Initialize an empty cell array to store function handles
            Obj_functionHandles = {};

            % Define the input variable names
            N_Var = app.EditField_4.Value; % Get the number of variables
            inputVariables = cell(1, N_Var);
            for j = 1:N_Var
                inputVariables{j} = ['x' num2str(j)];
            end

            % Assume 'ListBox' is the handle to your ListBox UI component
            Obj_items = app.ListBox2.Items;

            % Loop through the items in the ListBox
            for i = 1:numel(Obj_items)
                % Get the function string from the ListBox item
                functionString = Obj_items{i};
                % disp(functionString)
                % Split the row into cells based on '|'
                functionString = strsplit(functionString, '|');
                % Trim whitespace from each cell
                Obj_expression = strtrim(functionString{2}); % Extract the expression from cell
                % disp(Obj_expression)
                % Replace variable names with input variable names
                for j = 1:N_Var
                    Obj_expression = strrep(Obj_expression, ['x' num2str(j)], ['x(:,' num2str(j) ')']);
                    % disp(Obj_expression)
                end

                % Create an anonymous function that accepts input variables
                try
                    % Define an anonymous function with the correct number of input arguments
                    anonymousFunction = Obj_expression;

                    % Append the function handle to the cell array
                    Obj_functionHandles{end+1} = str2func(['@(x)' anonymousFunction]);

                catch
                    % Handle any errors that may occur during conversion
                    fprintf('Error converting function string: %s\n', Obj_expression);
                end
            end
%%          Initialize an empty cell array to store constraint handles
            Con_functionHandles = {};
            Con_items = app.ListBox3.Items;
            for i = 1:numel(Con_items)
                % Get the function string from the ListBox item
                ConString = Con_items{i};
                % disp(ConString)
                % Split the row into cells based on '|'
                ConString = strsplit(ConString, '|');
                % Trim whitespace from each cell
                Con_expression = strtrim(ConString{2}); % Extract the expression from cell
                % disp(Con_expression)
                % Replace variable names with input variable names
                for j = 1:N_Var
                    Con_expression = strrep(Con_expression, ['x' num2str(j)], ['x(:,' num2str(j) ')']);
                    % disp(Con_expression)
                end

                % Create an anonymous function that accepts input variables
                try
                    % Define an anonymous function with the correct number of input arguments
                    anonymousCon = Con_expression;

                    % Append the function handle to the cell array
                    Con_functionHandles{end+1} = str2func(['@(x)' anonymousCon]);

                catch
                    % Handle any errors that may occur during conversion
                    fprintf('Error converting function string: %s\n', Con_expression);
                end
            end
% 
%             x = [lowerBounds; upperBounds]; % Replace with the specific input values
%             Con_functionHandles{1}(x)

            %% Objective Function Definition
            N_Obj = size(Obj_functionHandles, 2);

            % Create a cell array to store the individual objective functions
            individualObjectives = cell(1, N_Obj);

            % Construct the individual objective functions from the function handles
            for i = 1:N_Obj
                individualObjectives{i} = @(x) Obj_functionHandles{i}(x);
            end

            % Create the combined multi-objective function
            fun = @(x) cellfun(@(f) f(x), individualObjectives);

            for i=1:size(X,1)
                f(i,:) = fun(X(i,:));
            end
            % disp(f)
            % disp(fun(lowerBounds))


            % Constraint Function Definition
            N_Con = size(Con_functionHandles, 2);

            % Create a cell array to store the individual objective functions
            individualConstraints = cell(1, N_Con);

            % Construct the individual objective functions from the function handles
            for i = 1:N_Con
                individualConstraints{i} = @(x) Con_functionHandles{i}(x);
            end

            % Create the combined multi-objective function
            con = @(x) cellfun(@(g) g(x), individualConstraints);

            for i=1:size(X,1)
                g(i,:) = con(X(i,:));
            end
            % dataset
            data = [X, f, g];
            % data
            % Assuming data = [X, f, g]
            numVariables = size(X, 2);  % Number of variable columns in X
            numconstraint = size(g, 2);  % Number of function columns in g
            
            % Extracting the variables (x1, x2, ...)
            variableData = data(:, 1:numVariables);
            
            % Extracting the functions (g1, g2, ...)
            constraintData = data(:, numVariables + 2:end);
            
            % Creating cell arrays to store the data matrices
            variableDataCells = cell(1, numVariables);
            constraintDataCells = cell(1, numconstraint);
            
            % Loop to create separate data matrices for variables
            for i = 1:numVariables
                variableDataCells{i} = variableData(:, i);
            end
            
            % Loop to create separate data matrices for functions (g1, g2, ...)
            for i = 1:numconstraint
                constraintDataCells{i} = constraintData(:, i);
            end
            

            % Create separate data matrices for variables and functions
            datai = cell(1, numconstraint + 1);
            datai{1} = [variableData, f];
            % disp(['Data 1:']);
            % disp(datai{1});
            for i = 2:(numconstraint + 1)
                 datai{i} = [variableData, constraintDataCells{i-1}];
                
                % Do something with datai, for example, display it
                % disp(['Data' num2str(i) ':']);
                % disp(datai{i});
            end

            % Create cell array to store sData structures
            for j = 1:numVariables
                 componentNamesfull{j} = ['$x_' num2str(j) '$'];
            end
            componentNamesfull{numVariables + 1} = ['$f$'];
            for j = 1:numconstraint
                 componentNamesfull{numVariables + 1 + j} = ['$g_' num2str(j) '$'];
            end
            sData_F = som_data_struct(data,'comp_names', componentNamesfull);
            sData_F = som_normalize(sData_F,'range');
            sDataArray = cell(1, length(datai)); 
            
            for j = 1:numVariables
                 componentNames{j} = ['$x_' num2str(j) '$'];
            end
            componentNames{numVariables + 1} = ['$f$'];
            % disp(componentNames);
            sDataArray{1} = som_data_struct(datai{1}, 'comp_names', componentNames);
            sDataArray{1} = som_normalize(sDataArray{1}, 'range');
            % Loop to create sData structures
            for i = 2:length(datai)
                % Dynamic component names
                componentNames = cell(1, numVariables + 1);
                for j = 1:numVariables
                    componentNames{j} = ['$x_' num2str(j) '$'];
                end
                componentNames{numVariables + 1} = ['$g_' num2str(i-1) '$'];
                % disp(componentNames);
                % Create som_data_struct and normalize
                sDataArray{i} = som_data_struct(datai{i}, 'comp_names', componentNames);
                sDataArray{i} = som_normalize(sDataArray{i}, 'range');
            end
            % disp(sDataArray{1});
            [sMap_F]= som_lininit(sData_F,'lattice','hexa','msize',[20,20]);
            [sMap]= modifiedsom_lininit(sDataArray{1},'lattice','hexa','msize',[20,20]);

            numSMaps = length(sDataArray);

            % Create an array to store sMap structures
            sMapArray = cell(1, numSMaps);
            sTrainArray = cell(1, numSMaps);
            
            % Loop to create sMap structures
            for i = 1:numSMaps
                % Create sMap and store in the array
                sMapArray{i} = sMap;
            end

            [sMap_F,sTrainF] = som_batchtrain(sMap_F,sData_F,'sample_order','ordered','trainlen',100,'rad_init',1.25, 'rad_fin', 0.5);

            % Train the sMap structures
            for i = 1:numSMaps
                [sMapArray{i}, sTrainArray{i}] = modifiedsom_batchtrain(sMapArray{i}, sDataArray{i}, 'sample_order', 'ordered', 'radius_ini', 1.0, 'radius_fin', 0.75, 'trainlen', 100);
            end

            sMap_F = som_denormalize(sMap_F,sData_F); 
            sData_F = som_denormalize(sData_F,'remove');

            for i = 1:numSMaps
                sMapArray{i} = som_denormalize(sMapArray{i},sDataArray{i});    
                sDataArray{i} = som_denormalize(sDataArray{i},'remove');
            end


            %% Re-arranging the data
            sMap_umatrix = sMap_F;
            sMap_umatrix.codebook(:,1:numVariables+1) = sMapArray{1}.codebook(:,1:numVariables+1);
            for i = 1:numconstraint
                sMap_umatrix.codebook(:,numVariables+1+i) = sMapArray{i+1}.codebook(:,numVariables+1);
            end
            
            constraints = componentNamesfull;
    
            numsliders = numVariables + 1 + numconstraint;
            sliders = zeros(1, numsliders); % Initialize as a numeric array
            
            % Assign 0 for variables
            sliders(1:numVariables) = 0;
            
            % Assign 0 for the objective function
            sliders(numVariables + 1) = 0;
            
            % Assign 1 for constraint functions
            sliders(numVariables + 2:end) = 1;
            disp(sliders);

            figure(1)
            som_show(sMap_umatrix);
            figure(2); hold on;
            som_show_slider_final1(sMap_umatrix, constraints, 2, sliders, [], 'comp', 'all','bar','horiz');
            % figure(1)
            % som_show(sMap_umatrix);
%             %% Add iSOM Plots
% 
%             numVariables = numel(lowerBounds);
% 
%             % Generate meshgrid based on lower bounds, upper bounds, and step sizes for each variable
%             evalString = 'meshgrid(';
%             for i = 1:numVariables
%                 evalString = [evalString, sprintf('%s:%s:%s', lowerBounds{i}, stepboxs{i}, upperBounds{i})];
%                 if i < numVariables
%                     evalString = [evalString, ', '];
%                 end
%             end
%             evalString = [evalString, ');'];
% 
%             % Evaluate the generated string to create the meshgrid
%             [X_cell{:}] = eval(evalString);
% 
%             % Reshape the meshgrid arrays to column vectors
%             X = cellfun(@(x) reshape(x, [], 1), X_cell, 'UniformOutput', false);
% 
%             % Convert the cell array to a matrix
%             X_matrix = cell2mat(X);
% 
%             % Use the generated meshgrid as the data
%             data = X_matrix;
% 
%             for i = 1:N_Obj
%                     iSOM_labels{i} = ['$f_' num2str(i) '$'];
%             end
% 
%             sData = som_data_struct(data,'my-data','comp_names',iSOM_labels);
%             sData = som_normalize(sData,'range');
%             N = floor(size(data,1)^0.5);
%             m_size = [N,N];
% 
%             [sMap]= som_lininit(sData,'lattice','hexa','msize',m_size);
%             [sMap,~] = som_batchtrain(sMap,sData,'sample_order','ordered','radius_ini',1.5,'radius_fin',0.95,'trainlen',500);
%             sMap = som_denormalize(sMap,sData); sData = som_denormalize(sData,'remove');
% 
%             % Get the screen size
%             screenSize = get(0, 'ScreenSize');
% 
%             % Create a figure and set its size to the screen size
% 
%             fig = figure('Position', 0.5*screenSize);
% 
% %             app.GeneratePFButton.UserData{2} = sMap;
%             dataCell = {fval, sMap, x};
%             app.GeneratePFButton.UserData = dataCell;
% %             app.GeneratePFButton.UserData{2}
%             som_show(sMap,'comp', 'all');
% %             som_show_add('hit',h_goal,'Markersize',1.0,'MarkerColor','k','EdgeColor','k','Subplot',1:N_Obj);
% %             sliders = ones([1,N_Obj]);
% %             som_show_slider_final(sMap, labels, N_Obj, N_Obj, sliders,[], 'comp', 'all','bar','horiz');
% %             h_Axes = app.UIAxes2;
%             figureOuterPosition = [100, 100, 800, 600]; % Example values
% 
%             % Set the 'OuterPosition' property of the figure
%             set(fig, 'OuterPosition', figureOuterPosition);
% 
%             % Set the renderer to OpenGL for high-quality output
%             set(fig, 'Renderer', 'opengl');          
%             % Capture the content of the figure as an image
%             frame = getframe(fig);
% 
%             % Display the captured image in UIAxes2
%             imshow(frame.cdata, 'Parent', app.UIAxes2);
% 
%             xlabel(app.UIAxes2, [],'Interpreter','latex','FontSize',1);
%             ylabel(app.UIAxes2, [],'Interpreter','latex','FontSize',1);
%             title(app.UIAxes2, 'iSOM Plots');

            % %% iSOM
            % % Assuming lowerBounds, upperBounds, and stepBoxes are defined for each variable
            % 
            % % Determine the number of variables
            % numVariables = numel(lowerBounds);
            % 
            % % Initialize cell arrays to store meshgrid matrices
            % meshGrids = cell(1, numVariables);
            % 
            % % Generate meshgrid for each variable
            % for i = 1:numVariables
            %     % Generate a vector for the current variable using lower, step, and upper bounds
            %     variableValues = lowerBounds(i):stepboxs(i):upperBounds(i);
            % 
            %     % Create meshgrid for the current variable
            %     [X, Y] = meshgrid(variableValues, variableValues);
            % 
            %     % Store the meshgrid in the cell array
            %     meshGrids{i} = {X, Y};
            % end
            % 
            % % Now meshGrids{1} contains the meshgrid for x1, meshGrids{2} for x2, and so on
            % 
            % % meshGrids{1} contains X1, meshGrids{2} contains X2, and so on
            % % functionValues{1} contains f_x1, functionValues{2} contains f_x2, and so on
            % % constraintValues{1} contains g_x1, constraintValues{2} contains g_x2, and so on
            % 
            % numVariables = numel(meshGrids);
            % numFunctions = numel(individualObjectives);
            % numConstraints = numel(individualConstraints);
            % 
            % % Initialize cell arrays to store reshaped data
            % dataCell = cell(1, numVariables + numFunctions + numConstraints);
            % dataCellFunctions = cell(1, numFunctions);
            % dataCellConstraints = cell(1, numConstraints);
            % 
            % % Reshape meshgrid matrices for each variable
            % for i = 1:numVariables
            %     variableName = sprintf('x%d', i);
            %     variableData = reshape(meshGrids{i}, [], 1);
            %     dataCell{i} = [variableName, variableData];
            % end
            % 
            % % Reshape function values for each function
            % for i = 1:numFunctions
            %     functionName = sprintf('f%d', i);
            %     functionData = reshape(individualObjectives{i}, [], 1);
            %     dataCellFunctions{i} = [functionName, functionData];
            % end
            % 
            % % Reshape constraint values for each constraint
            % for i = 1:numConstraints
            %     constraintName = sprintf('g%d', i);
            %     constraintData = reshape(individualConstraints{i}, [], 1);
            %     dataCellConstraints{i} = [constraintName, constraintData];
            % end
            % 
            % % Combine all reshaped data
            % dataCell = [dataCell, dataCellFunctions, dataCellConstraints];
            % 
            % % Create separate datasets for variables, functions, and constraints
            % data = cat(2, dataCell{:});
            % dataFunctions = cat(2, dataCellFunctions{:});
            % dataConstraints = cat(2, dataCellConstraints{:});
            % 
            % % Assuming you have a cell array containing data for variables, functions, and constraints
            % % dataCell{1} contains data for variables, dataCell{2} for functions, and dataCell{3} for constraints
            % 
            % numComponents = numel(dataCell);
            % 
            % % Initialize cell arrays to store SOM data structures
            % sDataCell = cell(1, numComponents);
            % 
            % % Create and normalize SOM data structures for each component
            % for i = 1:numComponents
            %     componentData = dataCell{i};
            % 
            %     % Define component names based on the type (variables, functions, constraints)
            %     if i <= numVariables
            %         componentNames = arrayfun(@(j) sprintf('$x_%d$', j), 1:numVariables, 'UniformOutput', false);
            %     elseif i <= numVariables + numFunctions
            %         componentNames = arrayfun(@(j) sprintf('$f_%d$', j - numVariables), 1:numFunctions, 'UniformOutput', false);
            %     else
            %         componentNames = arrayfun(@(j) sprintf('$g_%d$', j - numVariables - numFunctions), 1:numConstraints, 'UniformOutput', false);
            %     end
            % 
            %     % Create SOM data structure and normalize
            %     sData = som_data_struct(componentData, 'comp_names', componentNames);
            %     sData = som_normalize(sData, 'range');
            % 
            %     % Store the SOM data structure in the cell array
            %     sDataCell{i} = sData;
            % end
            % 
            % % Access SOM data structures for variables, functions, and constraints
            % sDataVariables = sDataCell{1};
            % sDataFunctions = sDataCell{2};
            % sDataConstraints = sDataCell{3};
            % 
            % % Assuming you have cell arrays for sData_F, sData1, sData2, sData3, ... (sDataCell{1} for sData_F, sDataCell{2} for sData1, and so on)
            % 
            % numSOMs = numel(sDataCell);
            % 
            % % Initialize cell arrays to store SOM structures
            % sMapCell = cell(1, numSOMs);
            % 
            % % Initialize the SOM structures for each component
            % for i = 1:numSOMs
            %     currentSData = sDataCell{i};
            % 
            %     % Initialize the SOM structure
            %     sMap = som_lininit(currentSData, 'lattice', 'hexa', 'msize', [20, 20]);
            % 
            %     % Optionally modify the SOM structure (uncomment if needed)
            %     % sMap.codebook(:, 6) = sMap.codebook(:, 6) * 0;
            % 
            %     % Store the SOM structure in the cell array
            %     sMapCell{i} = sMap;
            % end
            % 
            % % Access SOM structures for variables, functions, and constraints
            % sMapVariables = sMapCell{1};
            % sMapFunctions = sMapCell{2};
            % sMapConstraints = sMapCell{3};
            % 
            % % Assuming you have cell arrays for sMap_F, sData1, sData2, sData3, ... (sMapCell{1} for sMap_F, sMapCell{2} for sMap1, and so on)
            % % Also, assuming you have cell arrays for sTrainF, sTrain1, sTrain2, sTrain3, ... (sTrainCell{1} for sTrainF, sTrainCell{2} for sTrain1, and so on)
            % 
            % numSOMs = numel(sMapCell);
            % 
            % % Training parameters
            % sampleOrder = 'ordered';
            % trainLength = 100;
            % radiusIni = 1.0;
            % radiusFin = 0.75;
            % 
            % % Train SOMs for each component
            % for i = 1:numSOMs
            %     currentSMap = sMapCell{i};
            %     currentSData = sDataCell{i};
            % 
            %     % Train the SOM
            %     [currentSMap, currentSTrain] = som_batchtrain(currentSMap, currentSData, ...
            %         'sample_order', sampleOrder, 'radius_ini', radiusIni, 'radius_fin', radiusFin, 'trainlen', trainLength);
            % 
            %     % Store the trained SOM and training data in the cell arrays
            %     sMapCell{i} = currentSMap;
            %     sTrainCell{i} = currentSTrain;
            % end
            % 
            % % Access trained SOMs and training data for variables, functions, and constraints
            % sMapVariables = sMapCell{1};
            % sMapFunctions = sMapCell{2};
            % sMapConstraints = sMapCell{3};
            % 
            % sTrainVariables = sTrainCell{1};
            % sTrainFunctions = sTrainCell{2};
            % sTrainConstraints = sTrainCell{3};
            % 
            % 
            % % Assuming you have cell arrays for sMap_F, sData1, sData2, sData3, ... (sMapCell{1} for sMap_F, sMapCell{2} for sMap1, and so on)
            % 
            % numSOMs = numel(sMapCell);
            % 
            % % Denormalize the data for each SOM
            % for i = 1:numSOMs
            %     currentSMap = sMapCell{i};
            %     currentSData = sDataCell{i};
            % 
            %     % Denormalize the SOM
            %     currentSMap = som_denormalize(currentSMap, currentSData);
            % 
            %     % Denormalize the original data and remove normalization
            %     currentSData = som_denormalize(currentSData, 'remove');
            % 
            %     % Store the denormalized SOM and data back in the cell arrays
            %     sMapCell{i} = currentSMap;
            %     sDataCell{i} = currentSData;
            % end
            % 
            % % Access denormalized SOMs and data for variables, functions, and constraints
            % sMapVariables = sMapCell{1};
            % sMapFunctions = sMapCell{2};
            % sMapConstraints = sMapCell{3};
            % 
            % sDataVariables = sDataCell{1};
            % sDataFunctions = sDataCell{2};
            % sDataConstraints = sDataCell{3};
            % 
            % % Assuming you have cell arrays for sMap1, sMap2, sMap3, ... (sMapCell{1} for sMap1, sMapCell{2} for sMap2, and so on)
            % % Also, assuming you have sMap_umatrix initialized with sMap_F
            % 
            % numSOMs = numel(sMapCell);
            % 
            % % Initialize cell arrays to store rearranged data
            % sMap_umatrixVariablesCell = cell(1, numSOMs);
            % sMap_umatrixFunctionsCell = cell(1, numSOMs);
            % sMap_umatrixConstraintsCell = cell(1, numSOMs);
            % 
            % % Rearrange the data for each SOM
            % for i = 1:numSOMs
            %     currentSMap = sMapCell{i};
            % 
            %     % Store the rearranged data in cell arrays
            %     sMap_umatrixVariablesCell{i} = currentSMap.codebook(:, 1:3);
            %     sMap_umatrixFunctionsCell{i} = currentSMap.codebook(:, 4);
            %     sMap_umatrixConstraintsCell{i} = currentSMap.codebook(:, 5);
            % end
            % 
            % % Concatenate the cell arrays to obtain the final rearranged data
            % sMap_umatrixVariables = cat(2, sMap_umatrixVariablesCell{:});
            % sMap_umatrixFunctions = cat(2, sMap_umatrixFunctionsCell{:});
            % sMap_umatrixConstraints = cat(2, sMap_umatrixConstraintsCell{:});
            % 
            % % Assuming you have information about the number of variables, functions, and constraints
            % numVariables = numel(meshGrids);
            % numFunctions = numel(individualObjectives);
            % numConstraints = numel(individualConstraints);
            % 
            % % Generate variable names based on the number of variables, functions, and constraints
            % variableNames = cell(1, numVariables);
            % functionNames = cell(1, numFunctions);
            % constraintNames = cell(1, numConstraints);
            % 
            % for i = 1:numVariables
            %     variableNames{i} = sprintf('x%d', i);
            % end
            % 
            % for i = 1:numFunctions
            %     functionNames{i} = sprintf('f%d', i);
            % end
            % 
            % for i = 1:numConstraints
            %     constraintNames{i} = sprintf('g%d', i);
            % end
            % 
            % % Concatenate variable names, function names, and constraint names
            % cons = [variableNames, functionNames, constraintNames];
            % 
            % % Create sliders vector (1 for constraints, 0 for variables and functions)
            % sliders = [zeros(1, numVariables + numFunctions), ones(1, numConstraints)];
            % 
            % % Plot using som_show_slider_final1
            % figure(2); hold on;
            % som_show_slider_final1(sMap_umatrix, cons, 2, sliders, [], 'comp', 'all', 'bar', 'horiz');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LowerBoundEditFieldLabel = uilabel(app.UIFigure);
            app.LowerBoundEditFieldLabel.Text = 'Lower Bound';
            app.LowerBoundEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpperBoundEditFieldLabel = uilabel(app.UIFigure);
            app.UpperBoundEditFieldLabel.Text = 'Upper Bound';
            app.UpperBoundEditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3Label = uilabel(app.UIFigure);
            app.EditField_3Label.Text = 'Variables';
            app.EditField_3Label_2 = uilabel(app.UIFigure);
            app.EditField_3Label_2.Text = 'Functions';
            app.EditField_3Label_3 = uilabel(app.UIFigure);
            app.EditField_3Label_3.Text = 'Constraints';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            app.ListBox2 = uilistbox(app.UIFigure);
            app.ListBox2.Items = {};
            app.ListBox2.Value = {};
            app.ListBox3 = uilistbox(app.UIFigure);
            app.ListBox3.Items = {};
            app.ListBox3.Value = {};
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField2_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2_2.ValueChangedFcn = createCallbackFcn(app, @EditField2_2ValueChanged, true);
            app.EditField3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField3.ValueChangedFcn = createCallbackFcn(app, @EditField3ValueChanged, true);
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'x'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'x';
            app.DropDown_2 = uidropdown(app.UIFigure);
            app.DropDown_2.Items = {'f'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = 'f';
            app.DropDown2 = uidropdown(app.UIFigure);
            app.DropDown2.Items = {'g'};
            app.DropDown2.ValueChangedFcn = createCallbackFcn(app, @DropDown2ValueChanged, true);
            app.DropDown2.Value = 'g';
            app.EditField_5 = uieditfield(app.UIFigure, 'text');
            app.EditField2_3 = uieditfield(app.UIFigure, 'text');
            app.StepEditFieldLabel = uilabel(app.UIFigure);
            app.StepEditFieldLabel.Text = 'Step';
            app.StepEditField = uieditfield(app.UIFigure, 'numeric');
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.AddButton_2 = uibutton(app.UIFigure, 'push');
            app.AddButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddButton_2Pushed, true);
            app.AddButton_2.Text = 'Add';
            app.AddButton_3 = uibutton(app.UIFigure, 'push');
            app.AddButton_3.ButtonPushedFcn = createCallbackFcn(app, @AddButton_3Pushed, true);
            app.AddButton_3.Text = 'Add';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end